classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        LoadImageMenu    matlab.ui.container.Menu
        FromDiskMenu     matlab.ui.container.Menu
        Menu             matlab.ui.container.Menu
        CreditsMenu      matlab.ui.container.Menu
        ShowCreditsMenu  matlab.ui.container.Menu
        Label            matlab.ui.control.Label
        CompressButton   matlab.ui.control.Button
        QFSlider         matlab.ui.control.Slider
        QFSliderLabel    matlab.ui.control.Label
        compressedAxes   matlab.ui.control.UIAxes
        originalAxes     matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: FromDiskMenu
        function loadImg(app, event)
            [file, path] = uigetfile({'*.*'}, 'Select an Image', 'C:/'); % gets path and fill
            if(ischar(path))
                filePath = fullfile(path,file); % converts to one line
                originalImage = imread(filePath); %reads image
                set(0,'UserData', originalImage)
                imshow(originalImage, 'Parent', app.originalAxes);
            end
        end

        % Button pushed function: CompressButton
        function CompressButtonPushed(app, event)
            qualFact = app.QFSlider.Value;
            ogImg = get(0,'UserData');
            ycbImg = rgb2ycbcr(ogImg); %converts to ycbcr
            dctImg= dctTransform(ycbImg);
            %quantize
            %compressedImg = imgCompress(app, qualFact, ycbImg);
            %dequantize
            %idctImg = idctTransform(compressedImg);
            finalImg = ycbcr2rgb(dctImg);
            imshow(finalImg, 'Parent', app.compressedAxes);
        end

        % Value changed function: QFSlider
        function QFSliderValueChanged(app, event)
            value = app.QFSlider.Value;
            strVal = num2str(value);
            app.Label.Text=strVal;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadImageMenu = uimenu(app.UIFigure);
            app.LoadImageMenu.Text = 'Load Image';
            app.FromDiskMenu = uimenu(app.LoadImageMenu);
            app.FromDiskMenu.MenuSelectedFcn = createCallbackFcn(app, @loadImg, true);
            app.FromDiskMenu.Text = 'From Disk';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Enable = 'off';
            app.Menu.Text = '|';
            app.CreditsMenu = uimenu(app.UIFigure);
            app.CreditsMenu.Text = 'Credits';
            app.ShowCreditsMenu = uimenu(app.CreditsMenu);
            app.ShowCreditsMenu.Checked = 'on';
            app.ShowCreditsMenu.Text = 'Show Credits';
            app.originalAxes = uiaxes(app.UIFigure);
            title(app.originalAxes, 'Original')
            app.originalAxes.XTick = [];
            app.originalAxes.YTick = [];
            app.compressedAxes = uiaxes(app.UIFigure);
            title(app.compressedAxes, 'Title')
            app.compressedAxes.XTick = [];
            app.compressedAxes.YTick = [];
            app.QFSliderLabel = uilabel(app.UIFigure);
            app.QFSliderLabel.Text = 'QF';
            app.QFSlider = uislider(app.UIFigure);
            app.QFSlider.Limits = [0 10];
            app.QFSlider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.QFSlider.ValueChangedFcn = createCallbackFcn(app, @QFSliderValueChanged, true);
            app.QFSlider.MinorTicks = [];
            app.CompressButton = uibutton(app.UIFigure, 'push');
            app.CompressButton.ButtonPushedFcn = createCallbackFcn(app, @CompressButtonPushed, true);
            app.CompressButton.Text = 'Compress';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end